classdef CaPuLeT_GUI_2P_Audio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        DeviceTab                       matlab.ui.container.Tab
        SDisconnectButton               matlab.ui.control.Button
        SConnectButton                  matlab.ui.control.StateButton
        Lamp                            matlab.ui.control.Lamp
        SerialStatusEditField           matlab.ui.control.EditField
        StatusEditField_2Label          matlab.ui.control.Label
        RefreshPortButton               matlab.ui.control.Button
        COMportDropDown                 matlab.ui.control.DropDown
        COMportDropDownLabel            matlab.ui.control.Label
        ExperimentInfoTab               matlab.ui.container.Tab
        StimulusEditField               matlab.ui.control.EditField
        StimulusEditFieldLabel          matlab.ui.control.Label
        MouseGenotypeEditField          matlab.ui.control.EditField
        MouseGenotypeEditFieldLabel     matlab.ui.control.Label
        MouseIDEditField                matlab.ui.control.EditField
        MouseIDEditFieldLabel           matlab.ui.control.Label
        ProjectNameEditField            matlab.ui.control.EditField
        ProjectNameEditFieldLabel       matlab.ui.control.Label
        ResearcherNameEditField         matlab.ui.control.EditField
        ResearcherNameEditFieldLabel    matlab.ui.control.Label
        ExperimentTimeEditField         matlab.ui.control.NumericEditField
        ExperimentTimeEditFieldLabel    matlab.ui.control.Label
        SessiontypeDropDown             matlab.ui.control.DropDown
        SessiontypeLabel                matlab.ui.control.Label
        FileSaveTab                     matlab.ui.container.Tab
        FilenamestatusEditField         matlab.ui.control.EditField
        FilenamestatusLabel             matlab.ui.control.Label
        SetfilenameButton               matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        FolderpathEditField             matlab.ui.control.EditField
        FolderpathEditFieldLabel        matlab.ui.control.Label
        DateDatePicker                  matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        CalciumimagingPupillometryandLocomotionestimatedTrackingLabel  matlab.ui.control.Label
        CaPuLeTLabel                    matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        CalciumimagingTab               matlab.ui.container.Tab
        OtherdevicesPanel               matlab.ui.container.Panel
        OtherCheckBox                   matlab.ui.control.CheckBox
        Otherdevicetesttrigger          matlab.ui.control.StateButton
        OptogeneticsCheckBox_3          matlab.ui.control.CheckBox
        TointegrateotherdevicestobesynchronizedwithLabel  matlab.ui.control.Label
        CalciumimagingdevicesPanel      matlab.ui.container.Panel
        LampCalciumImagingTest          matlab.ui.control.Lamp
        CalciumImagingStatusTextArea    matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        TwoPhotonTriggerButton          matlab.ui.control.StateButton
        MultiphotonsystemLabel          matlab.ui.control.Label
        TwoPhotonCheckBox               matlab.ui.control.CheckBox
        InorderintegrateneuralrecordingsormodulationLabel  matlab.ui.control.Label
        CalciumimagingboxUIAxes         matlab.ui.control.UIAxes
        PupillometryTab                 matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        PupilCameraTab                  matlab.ui.container.Tab
        LoggingParametersPanel          matlab.ui.container.Panel
        ConfigureCameraButton           matlab.ui.control.Button
        CamTriggerBox                   matlab.ui.control.CheckBox
        TriggerCameraLabel              matlab.ui.control.Label
        CamFileFormat                   matlab.ui.control.DropDown
        FileFormatLabel                 matlab.ui.control.Label
        ROIWidthSpinner                 matlab.ui.control.Spinner
        WidthSpinnerLabel               matlab.ui.control.Label
        ROIHeightSpinner                matlab.ui.control.Spinner
        HeightSpinnerLabel              matlab.ui.control.Label
        ROIYPositionSpinner             matlab.ui.control.Spinner
        YPositionSpinnerLabel           matlab.ui.control.Label
        ROIXPositionSpinner             matlab.ui.control.Spinner
        XPositionSpinnerLabel           matlab.ui.control.Label
        ROIPanel                        matlab.ui.container.Panel
        ROIPositionButtonGroup          matlab.ui.container.ButtonGroup
        UndoApplyButton                 matlab.ui.control.Button
        ApplyROIButton                  matlab.ui.control.Button
        ResetROIButton                  matlab.ui.control.Button
        SelectROIButton                 matlab.ui.control.StateButton
        CameraSettingsPanel             matlab.ui.container.Panel
        CamSetResetButton               matlab.ui.control.Button
        IRLEDSpinner                    matlab.ui.control.Spinner
        IRLEDSlider                     matlab.ui.control.Slider
        IRLabel                         matlab.ui.control.Label
        UVLEDSpinner                    matlab.ui.control.Spinner
        UVLEDSlider                     matlab.ui.control.Slider
        UVLabel                         matlab.ui.control.Label
        CamGainSpinner                  matlab.ui.control.Spinner
        CamGainSlider                   matlab.ui.control.Slider
        Gain                            matlab.ui.control.Label
        CamExposureSpinner              matlab.ui.control.Spinner
        CamExposureSlider               matlab.ui.control.Slider
        ExposureSliderLabel             matlab.ui.control.Label
        CamFrameRateEditField           matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel         matlab.ui.control.Label
        CamDeviceConnectionPanel        matlab.ui.container.Panel
        PreviewCameraSwitch             matlab.ui.control.Switch
        PreviewCameraSwitchLabel        matlab.ui.control.Label
        CamDisconnectButton             matlab.ui.control.Button
        CamConnectButton                matlab.ui.control.Button
        CamStatusEditField              matlab.ui.control.EditField
        StatusLabel                     matlab.ui.control.Label
        CamFormatDropDown               matlab.ui.control.DropDown
        FormatDropDownLabel             matlab.ui.control.Label
        CamRefreshButton                matlab.ui.control.Button
        CamDropDown                     matlab.ui.control.DropDown
        CameraDropDownLabel             matlab.ui.control.Label
        PupilCameraUIAxes               matlab.ui.control.UIAxes
        LocomotionestimationTab         matlab.ui.container.Tab
        LoggingPanel                    matlab.ui.container.Panel
        ConfigureWheelStatusEditField   matlab.ui.control.EditField
        StatusEditFieldLabel            matlab.ui.control.Label
        ConfigureWheelButton            matlab.ui.control.Button
        WheelFiletypeDropDown           matlab.ui.control.DropDown
        FiletypeDropDownLabel           matlab.ui.control.Label
        AcquisitionSpeedPanel           matlab.ui.container.Panel
        StopWheelPreview                matlab.ui.control.Button
        StartWheelPreviewButton         matlab.ui.control.StateButton
        WheelSamplingPeriod             matlab.ui.control.NumericEditField
        SamplingratefordataHzEditFieldLabel  matlab.ui.control.Label
        LocomotionwheelPanel            matlab.ui.container.Panel
        LocomotionUIAxes                matlab.ui.control.UIAxes
        TrackingTab                     matlab.ui.container.Tab
        TrackingStatusEditField         matlab.ui.control.EditField
        StatusEditFieldLabel_2          matlab.ui.control.Label
        LoadExperimentParametersButton  matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.StateButton
        PreviewWindowPanel              matlab.ui.container.Panel
        PreviewLocomotionWheelCheckBox  matlab.ui.control.CheckBox
        PreviewPupilCameraCheckBox      matlab.ui.control.CheckBox
        SelectedDevicesforExperimentPanel  matlab.ui.container.Panel
        Otherdevice                     matlab.ui.control.CheckBox
        LocomotionWheelCheckBox         matlab.ui.control.CheckBox
        IRLedCheckBox                   matlab.ui.control.CheckBox
        PupilCameraCheckBox             matlab.ui.control.CheckBox
        UVLedCheckBox                   matlab.ui.control.CheckBox
        CalciumImagingCheckBox          matlab.ui.control.CheckBox
        FinalPupilCameraUIAxes          matlab.ui.control.UIAxes
        FinalLocomotionWheelUIAxes      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        errorOccurred = "false" % Error condition to stop certain code if error appeared
        SerialPort % Serial port connection to Arduino
        SerialObject % Object for Serial Communication
        saveFilePath % Filelocation to save files
        saveFileName % Filepath name used to label files
        DataTimer % Description
        WheelLog % Preallocated array for data collection of wheel
        StatusVector % Vector that contains instructions for operating Arduino
        caminfolist % List of camera information
        camString % String that will be updated with camera format changes
        pupilcamera % Object for pupil camera
        camres % Pupil camera resolution
        nbands % Number of bands of pupilcamera
        hImage % Preview camera
        fImage % Final image for camera
        inRectPos % Initial position for roi rectangle
        newRectPos % New position of roi rectange
        roi % roi for pupil size
        srccam % selected source of camera
        L % listener for ROI 
        L2 % Listener for ROI
        h % animated line
        h2 % animated line for final locomotion plot
        cm_per_step % ratio to convert encoder data to velocity
        previous_time = 0 % To store previous time
        timeData = [] % time and data to be stored for plotting of wheel
        velocityData = []
        fullfilenamewheel % filename for wheel data to be logged
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function CameraPanel(app)
            delete(imaqfind);
            imaqreset;
            imaqInfo = imaqhwinfo;
            adaptors = imaqInfo.InstalledAdaptors; %Extract list of adaptors
            cameraList = {}; %Empty list for camera devices

            if ~isempty(adaptors)
                %check for devices in each adaptor
                for i = 1:numel(adaptors)
                    hWInfo = imaqhwinfo(adaptors{i});
                    devices = hWInfo.DeviceInfo;
                    if ~isempty(devices)
                        for j = 1:numel(devices)
                            deviceInfo = devices(j);
                            supportedFormats = strjoin(deviceInfo.SupportedFormats, ', ');
                            deviceInfoArray = {deviceInfo.DeviceName, deviceInfo.VideoInputConstructor, deviceInfo.VideoDeviceConstructor,...
                                deviceInfo.DefaultFormat, supportedFormats};
                            cameraList = [cameraList, deviceInfoArray];               
                        end
                    end
                end
            else
                app.CamStatusEditField.Value = 'No camera adaptors found';
            end
            if ~isempty(cameraList)
                devicelist = cameraList(:,1);
                app.CamDropDown.Items = ['Select Camera', devicelist];
                app.caminfolist = cameraList;
                app.CamStatusEditField.Value = 'Cameras detected';
            else
                app.CamStatusEditField.Value = 'No camera detected, check if installed correct adaptor';
            end
            
        end

        function allevents(app, src, event)
            evname = event.EventName;
            try
                switch evname
                    case "MovingROI"
                        %disp(round(event.CurrentPosition));
                    app.ROIXPositionSpinner.Value = round(event.CurrentPosition(1));
                    app.ROIYPositionSpinner.Value = round(event.CurrentPosition(2));
                    app.ROIWidthSpinner.Value = round(event.CurrentPosition(3));
                    app.ROIHeightSpinner.Value = round(event.CurrentPosition(4));
                    case "ROIMoved"
                        %disp(round(event.CurrentPosition));
                    app.ROIXPositionSpinner.Value = round(event.CurrentPosition(1));
                    app.ROIYPositionSpinner.Value = round(event.CurrentPosition(2));
                    app.ROIWidthSpinner.Value = round(event.CurrentPosition(3));
                    app.ROIHeightSpinner.Value = round(event.CurrentPosition(4));
                end
            catch ME
                disp('Error: ', ME.message);
            end
        end

        function sendstatus(app)
            try
                if isvalid(app.SerialObject) && strcmp(app.SerialObject.Status, 'open')
                    dataToSend = sprintf('%d,', app.StatusVector);
                    dataToSend = dataToSend(1:end-1);
                    % Send the string on the serial port
                    fprintf(app.SerialObject, '%s', dataToSend);
                    app.errorOccurred = "false";
                else
                    % Display a message if the serial port is not open
                    errordlg('Error: Serial port not open','Error');
                end
            catch 
                errordlg('Error: Serial port not open', 'Error');
                app.errorOccurred = "true";
            end
        end
        
        function camdefault(app)
            try
                if isvalid(app.pupilcamera)
                    app.srccam = getselectedsource(app.pupilcamera);
                    app.srccam.TriggerMode = "Off"; %sometimes need to do this weird trigger off vs on to be able to access camera again
                    app.srccam.TriggerMode = "On";
                    app.srccam.TriggerMode = "Off";
                end
            catch ME
                app.CamStatusEditField.Value = ['Error: ', ME.message];
            end

            frame = 10; % typical frame rate for aquisition 
            IRLed = 255; 

            componentTags = {'CamExposureSpinner', 'CamExposureSlider', 'CamGainSpinner', 'CamGainSlider', 'CamFrameRateEditField',...
                'UVLEDSpinner', 'UVLEDSlider',...
                'IRLEDSpinner', 'IRLEDSlider'};

            %loop through components and reset the values to lower
            %limit. Compare strings to make it quicker
            for i = 1:length(componentTags)
                componentName = componentTags{i};
                if contains(componentName, "CamExposure")
                    app.(componentName).Value  = mean(app.CamExposureSpinner.Limits);
                    app.srccam.ExposureTime = app.CamExposureSpinner.Value;
                elseif contains(componentName, "CamGain")
                    app.(componentName).Value = mean(app.CamGainSpinner.Limits);
                    app.srccam.Gain = app.CamGainSpinner.Value;
                elseif contains(componentName, "CamFrameRate")
                    app.(componentName).Value = frame;
                    app.srccam.AcquisitionFrameRate = frame;
                elseif contains(componentName, "UVLED")
                    app.(componentName).Value = mean(app.UVLEDSpinner.Limits);
                    app.StatusVector(1) = app.UVLEDSpinner.Value;
                elseif contains(componentName, "IRLED")
                    app.(componentName).Value = IRLed;
                    app.StatusVector(2) = IRLed;
                end
            end
            try
                if isvalid(app.SerialObject) && strcmp(app.SerialObject.Status, 'open')
                    sendstatus(app);
                end
            catch ME
                app.CamStatusEditField.Value = ['Error: ', ME.message];
            end 
        end
    end


   methods (Access = public)
 

   end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %Create what ports available to connect in beginning
            serialPorts = serialportlist("available");
            app.COMportDropDown.Items = ['Select...', serialPorts];
            app.SerialPort= app.COMportDropDown.Value; 

            %Status Vector for communicating with Arduino
            app.StatusVector = [0,0,0,0,50,0,0,0,0,0];
            app.WheelSamplingPeriod.Value = 50; %Already set the default for the wheel

            %Set up properties of locomotion wheel axes
            app.LocomotionUIAxes.YGrid = 'on';
            app.LocomotionUIAxes.XGrid = 'on';
            app.LocomotionUIAxes.YLim = [-20 20];
            app.LocomotionUIAxes.YTick = -20:5:20;
            app.LocomotionUIAxes.XLim = [0 20];

            %Calculations for encoder to be converted to velocity
            circumference_cm = 67.83; % Circumference of the wheel in centimeters
            app.cm_per_step = circumference_cm/1024; % cm per encoder step. 1024 is resolution of encoder
            app.WheelLog = [];

            %Set default path name and path directory just in case people
            % don't fill out where to save their files
            app.saveFilePath = pwd;
            Dateorig = datetime('now', 'Format', 'MMddyy');
            Test = 'TestCollection';
            Date = string(Dateorig);
            Dateminorig = datetime('now','Format','HH:mm:ss'); %getting mi
            Datemin = string(Dateminorig);
            Datemin = strrep(Datemin, ':','');
            filenamedefault = append(Test, '_', Date, '_', Datemin);
            app.saveFileName = filenamedefault;

            % delete all objects from memory
            delete(imaqfind);
            imaqreset;

        end

        % Button pushed function: RefreshPortButton
        function RefreshPortButtonPushed(app, event)
            serialPorts = serialportlist("available");
            app.COMportDropDown.Items = ['Select...', serialPorts];
        end

        % Value changed function: COMportDropDown
        function COMportDropDownValueChanged(app, event)
            app.SerialPort = app.COMportDropDown.Value;
            app.SerialStatusEditField.Value = ['Port Selected: ', app.SerialPort];
        end

        % Value changed function: SConnectButton
        function SConnectButtonValueChanged(app, event)
            if app.SConnectButton.Value
                try
                    baudRate = 57600;         % Baud rate of arduino device
                    app.SerialObject = serialport(app.SerialPort, baudRate);
                    disp(app.SerialObject.Status);
                    sendstatus(app);
                    app.SerialStatusEditField.Value = 'Serial port opened';
                    app.Lamp.Color = [0.10 0.91 0.10];
                catch ME
                    if strcmp(ME.identifier, 'MATLAB:serialport:invalidPort')
                        app.SerialStatusEditField.Value = 'Error: Invalid serial port';
                    else
                        app.SerialStatusEditField.Value = ['Error: ', ME.message];
                    end
                end
            else
                try
                    fclose(app.SerialObject);
                    app.SerialStatusEditField.Value = 'Serial port closed';
                    app.Lamp.Color = [0.94 0.46 0.46];
                    delete(app.SerialObject);
                catch ME
                    app.SerialStatusEditField.Value = ['Error: ', ME.message];
                end
            end
            
        end

        % Button pushed function: SDisconnectButton
        function SDisconnectButtonPushed(app, event)
            app.SConnectButton.Value = 0;
            delete(app.SerialObject);    
            app.Lamp.Color = [0.94 0.46 0.46];
            app.SerialStatusEditField.Value = 'Serial port closed';
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            selectedFolder = uigetdir(); % Open dialog to select a folder
            if selectedFolder == 0
            % User canceled the operation
                disp('Folder selection canceled.');
                return;
            end
            % Update folder path to show selected folder path 
            app.FolderpathEditField.Value= ['Selected Folder: ' selectedFolder];
            app.saveFilePath = selectedFolder; %Save selected folder
        end

        % Value changed function: SessiontypeDropDown
        function SessiontypeDropDownValueChanged(app, event)
            value = app.SessiontypeDropDown.Value;
        end

        % Value changed function: ResearcherNameEditField
        function ResearcherNameEditFieldValueChanged(app, event)
            value = app.ResearcherNameEditField.Value;
            hasSpaces = contains(value, ' ');

            if hasSpaces
                app.ResearcherNameEditField.Value = strrep(value, ' ', '');
            end

        end

        % Value changed function: ProjectNameEditField
        function ProjectNameEditFieldValueChanged(app, event)
            value = app.ProjectNameEditField.Value;
            hasSpaces = contains(value, ' ');

            if hasSpaces
                app.ProjectNameEditField.Value = strrep(value, ' ', '');
            end 

        end

        % Value changed function: MouseIDEditField
        function MouseIDEditFieldValueChanged(app, event)
            value = app.MouseIDEditField.Value;
            hasSpaces = contains(value, ' ');

            if hasSpaces
                app.MouseIDEditField.Value = strrep(value, ' ', '');
            end
            
        end

        % Value changed function: MouseGenotypeEditField
        function MouseGenotypeEditFieldValueChanged(app, event)
            value = app.MouseGenotypeEditField.Value;
            hasSpaces = contains(value, ' ');

            if hasSpaces
                app.MouseGenotypeEditField.Value = strrep(value, ' ', '');
            end

        end

        % Value changed function: DateDatePicker
        function DateDatePickerValueChanged(app, event)
            value = app.DateDatePicker.Value;
            %dataType = class(value); Check datatype 
        
        end

        % Value changed function: StimulusEditField
        function StimulusEditFieldValueChanged(app, event)
            value = app.StimulusEditField.Value;
            hasSpaces = contains(value, ' ');

            if hasSpaces
                app.StimulusEditField.Value = strrep(value, ' ', '');
            end 

        end

        % Button pushed function: SetfilenameButton
        function SetfilenameButtonPushed(app, event)
           if isempty(app.DateDatePicker)
                Dateorig = datetime('now', 'Format', 'MMddyy');
                Date = string(Dateorig);
           else
                Dateorig = app.DateDatePicker.Value;
                Date = string(Dateorig);
                Date = strrep(Date, '.',''); %remove dot from date
           end

            %Shorten name of session type to not cause issues with 
            % compiling filename to be saved
            if contains(app.SessiontypeDropDown.Value, "Habituation")
                sess = 'Hab';
            else
                sess= 'Exp';
            end

           componentTags = {'StimulusEditField', 'ResearcherNameEditField', 'ProjectNameEditField',...
               'MouseIDEditField', 'MouseGenotypeEditField',...
                'StimulusEditField'};

           filename = '';  % Initialize an empty string for the filename.
           separator = '_';  % Define a separator, such as underscore.

           try
           for i=1:length(componentTags)
               componentName= componentTags{i};
               if ~isempty(app.(componentName))
                   if isempty(filename)
                       val = app.(componentName).Value;
                       filename = val; %Start filename with first non-empty variable
                   else
                       val = app.(componentName).Value;
                       filename = [filename separator val]; %Append other non-empty component tags
                   end
               end
           end

           Dateminorig = datetime('now','Format','HH:mm:ss'); %getting time format so files don't overwrite eachother
           Datemin = string(Dateminorig);
           Datemin = strrep(Datemin, ':',''); 

           %append Date at end
           filename = append(filename, separator, Date, separator, sess, separator, Datemin);
           app.saveFileName = filename;
           app.FilenamestatusEditField.Value = 'Filepath configured and saved';
           disp(app.saveFileName);
           catch ME
                app.FilenamestatusEditField.Value = ['Error: ', ME.message;];
           end

        end

        % Value changed function: TwoPhotonCheckBox
        function TwoPhotonCheckBoxValueChanged(app, event)
            app.CalciumImagingCheckBox.Value = app.TwoPhotonCheckBox.Value;
            
        end

        % Value changed function: TwoPhotonTriggerButton
        function TwoPhotonTriggerButtonValueChanged(app, event)
             try
                 if app.TwoPhotonTriggerButton.Value ==1
                        app.StatusVector(6) = 1;
                        app.StatusVector(7) = 1; 
                        sendstatus(app); %Send string
                        if app.errorOccurred =="true"
                            app.TwoPhotonTriggerButton.Value = 0;
                            app.TwoPhotonCheckBox.Value=0;
                            app.TwoPhotonCheckBoxValueChanged;
                            app.LampCalciumImagingTest.Color = [0.94 0.46 0.46]; 
                            app.CalciumImagingStatusTextArea.Value = 'Trigger Pulse Not Sent';
                        else
                            app.CalciumImagingStatusTextArea.Value = 'Trigger Pulse On';
                            app.LampCalciumImagingTest.Color = [0.10 0.91 0.10]; %Lamp color green
                        end
                 else
                        app.StatusVector(7) = 0; %configure serial string to reset RTC and to send trigger pulse
                        sendstatus(app); %Send string
                        if app.errorOccurred == "true"
                            app.TwoPhotonTriggerButton.Value = 0;
                            app.TwoPhotonCheckBox.Value=0;
                            app.TwoPhotonCheckBoxValueChanged;
                            app.LampCalciumImagingTest.Color = [0.94 0.46 0.46]; 
                            app.CalciumImagingStatusTextArea.Value = 'Trigger Pulse Not Sent';
                        else
                            app.CalciumImagingStatusTextArea.Value = 'Trigger Pulse Off';
                            app.LampCalciumImagingTest.Color = [0.94 0.46 0.46]; %Lamp color red
                        end
                 end
             catch ME
                 app.CalciumImagingStatusTextArea.Value = ['Error: ', ME.message];
             end
            
        end

        % Clicked callback: CamDropDown
        function CamDropDownClicked(app, event)
            CameraPanel(app);
        end

        % Button pushed function: CamRefreshButton
        function CamRefreshButtonPushed(app, event)
            CameraPanel(app);
        end

        % Value changed function: CamDropDown
        function CamDropDownValueChanged(app, event)
            selectedDevice = app.CamDropDown.Value;
            
            if ~strcmp(selectedDevice, app.CamDropDown.Items{1})
                index = find(strcmp(app.caminfolist, selectedDevice)); %Index to find supported formats
                camcommand = app.caminfolist{index,2};
                %disp(camcommand);
                app.camString = camcommand; %Save camera video input line
                %disp(app.camString); 
                caminfo = app.caminfolist{index,5}; %access supported formats and use as items for format drop down list
                formats = split(caminfo, ',');
                trimmedformats = cellfun(@strtrim, formats, 'UniformOutput', false);
                Formats = [{'Select...'}; trimmedformats];
                 %disp(Formats);
                app.CamFormatDropDown.Items = Formats;
            else
                app.CamFormatDropDown.Items = [{'Option 1'}; {'Option 2'}; {'Option 3'}; {'Option 4'}];
            end 
        end

        % Value changed function: CamFormatDropDown
        function CamFormatDropDownValueChanged(app, event)
            selectedFormat = app.CamFormatDropDown.Value;

            %Don't update camera command string if the value is the
            %first item
            if ~strcmp(selectedFormat, app.CamFormatDropDown.Items{1})
                %Use camString to update camera info by creating new command
                %string
                startIndex = strfind(app.camString, '('); 
                endIndex = strfind(app.camString, ')');   
                cameraInfo = app.camString(startIndex+1:endIndex-1);  
                newCommandString = ['videoinput(', cameraInfo,' , ''' selectedFormat ''')'];
                %disp(newCommandString); 
                app.camString = newCommandString;
                %disp(app.camString);
            else
                app.CamStatusEditField.Value = 'Select Camera Format';
            end             
        end

        % Button pushed function: CamConnectButton
        function CamConnectButtonPushed(app, event)
            app.pupilcamera = eval(app.camString); % connect camera

            if isvalid(app.pupilcamera)
                try
                app.srccam = getselectedsource(app.pupilcamera);
                app.inRectPos = app.pupilcamera.ROIPosition; %initial roi position
                app.nbands = app.pupilcamera.NumberofBands;
                %get(app.srccam); %Identify which properties can be changed
                %get(app.pupilcamera); %Identify which properties can be changed from the camera
               

                %Define temporary variables for the limits
                exposureLimits = [app.srccam.AutoExposureExposureTimeLowerLimit, app.srccam.AutoExposureExposureTimeUpperLimit];
                gainLimits = [app.srccam.AutoExposureGainLowerLimit, app.srccam.AutoExposureGainUpperLimit];
                ledLimits = [0, 255];
                roi1Limits = [0, app.inRectPos(3)]; %This is for x and width
                roi2Limits = [0, app.inRectPos(4)]; % this if for y and height

                %Set limits
                app.CamExposureSpinner.Limits = exposureLimits;
                app.CamExposureSlider.Limits = exposureLimits;
                app.CamGainSpinner.Limits = gainLimits;
                app.CamGainSlider.Limits = gainLimits;
                app.UVLEDSlider.Limits = ledLimits;
                app.UVLEDSpinner.Limits = ledLimits;
                app.IRLEDSlider.Limits = ledLimits;
                app.IRLEDSpinner.Limits = ledLimits;
                app.ROIXPositionSpinner.Limits = roi1Limits;
                app.ROIWidthSpinner.Limits = roi1Limits;
                app.ROIYPositionSpinner.Limits = roi2Limits;
                app.ROIHeightSpinner.Limits = roi2Limits;
                app.ROIXPositionSpinner.Value = app.inRectPos(1);
                app.ROIYPositionSpinner.Value = app.inRectPos(2);
                app.ROIWidthSpinner.Value = app.inRectPos(3);
                app.ROIHeightSpinner.Value = app.inRectPos(4);

                camdefault(app);
                app.CamStatusEditField.Value = 'Camera preview available';

                catch ME
                    app.CamStatusEditField.Value = ['Error: ', ME.message];
                end
            else
                app.CamStatusEditField.Value = 'Camera not valid';
            end

        end

        % Button pushed function: CamDisconnectButton
        function CamDisconnectButtonPushed(app, event)
           %Turn off preview if it is on
           if app.PreviewCameraSwitch.Value == "On"
               closepreview(app.pupilcamera);
               stop(app.pupilcamera);
               delete(app.hImage);
               app.PreviewCameraSwitch.Value = 'Off';
           end

           %stop camera and delete objects
           stop(app.pupilcamera);
           delete(app.pupilcamera);
           app.CamStatusEditField.Value = 'Reconnect Camera';
           delete(imaqfind);
           imaqreset;

        end

        % Button pushed function: CamSetResetButton
        function CamSetResetButtonPushed(app, event)
            camdefault(app);
            app.PreviewCameraSwitch.Value = 'Off';
            app.PreviewCameraSwitchValueChanged;
        end

        % Value changed function: CamExposureSlider
        function CamExposureSliderValueChanged(app, event)
            app.CamExposureSpinner.Value = app.CamExposureSlider.Value;
            app.srccam.ExposureTime = app.CamExposureSpinner.Value;
        end

        % Value changed function: CamExposureSpinner
        function CamExposureSpinnerValueChanged(app, event)
            app.CamExposureSlider.Value = app.CamExposureSpinner.Value;
            app.srccam.ExposureTime = app.CamExposureSpinner.Value;
        end

        % Value changed function: CamGainSlider
        function CamGainSliderValueChanged(app, event)
            app.CamGainSpinner.Value = app.CamGainSlider.Value;
            app.srccam.Gain = app.CamGainSpinner.Value;
        end

        % Value changed function: CamGainSpinner
        function CamGainSpinnerValueChanged(app, event)
            app.CamGainSlider.Value = app.CamGainSpinner.Value;
            app.srccam.Gain = app.CamGainSpinner.Value;
        end

        % Value changed function: CamFrameRateEditField
        function CamFrameRateEditFieldValueChanged(app, event)
            app.srccam.AcquisitionFrameRate = app.CamFrameRateEditField.Value;       
        end

        % Value changed function: UVLEDSlider
        function UVLEDSliderValueChanged(app, event)
            app.UVLEDSpinner.Value = round(app.UVLEDSlider.Value);
            app.StatusVector(1)= app.UVLEDSpinner.Value;
            sendstatus(app);
        end

        % Value changed function: UVLEDSpinner
        function UVLEDSpinnerValueChanged(app, event)
            app.UVLEDSlider.Value = app.UVLEDSpinner.Value;
            app.StatusVector(1)= app.UVLEDSlider.Value;
            sendstatus(app);
        end

        % Value changed function: IRLEDSlider
        function IRLEDSliderValueChanged(app, event)
            app.IRLEDSpinner.Value = round(app.IRLEDSlider.Value);  
            app.StatusVector(2)=app.IRLEDSpinner.Value;
            sendstatus(app);
        end

        % Value changed function: IRLEDSpinner
        function IRLEDSpinnerValueChanged(app, event)
            app.IRLEDSlider.Value = app.IRLEDSpinner.Value;
            app.StatusVector(2)=app.IRLEDSlider.Value;
            sendstatus(app);
        end

        % Value changed function: PreviewCameraSwitch
        function PreviewCameraSwitchValueChanged(app, event)
            if app.PreviewCameraSwitch.Value == "On"
                %get(app.pupilcamera);
                %get(app.srccam);
                app.PupilCameraUIAxes.XLim = [0 app.inRectPos(3)];
                app.PupilCameraUIAxes.YLim = [0 app.inRectPos(4)];
                emptyImage = zeros(app.inRectPos(4), app.inRectPos(3), app.nbands, 'uint8'); %create empty image for axis
                app.hImage = image(app.PupilCameraUIAxes, emptyImage);
                preview(app.pupilcamera, app.hImage);
            else
                closepreview(app.pupilcamera);
                delete(app.hImage);
                app.SelectROIButton.Value= 0;
                app.PupilCameraUIAxes.XLim = [0 app.inRectPos(3)];
                app.PupilCameraUIAxes.YLim = [0 app.inRectPos(4)];
                emptyImage = zeros(app.inRectPos(4), app.inRectPos(3), app.nbands, 'uint8'); %create empty image for axis
                app.hImage = image(app.PupilCameraUIAxes, emptyImage);
                app.StatusVector(1)=0;
                app.StatusVector(2)=0;
                sendstatus(app);

                if ~isempty(app.roi) && isvalid(app.roi) 
                    delete(app.roi);
                    delete(app.L);
                    delete(app.L2);
                end
            end

        end

        % Value changed function: SelectROIButton
        function SelectROIButtonValueChanged(app, event)
             if app.SelectROIButton.Value == 1
                 try
                     app.roi = images.roi.Rectangle(app.PupilCameraUIAxes,'Position', app.inRectPos, 'Color', [0.4940 0.1840 0.5560],...
                         'StripeColor', "white", 'FaceAlpha', 0.2, 'LineWidth', 1.5, ...
                         'DrawingArea',"auto",'InteractionsAllowed',"all");
                     disp(round(app.roi.Position));
                     app.L = addlistener(app.roi, 'ROIMoved', @app.allevents);
                     app.L2 = addlistener(app.roi, 'MovingROI', @app.allevents);
                 catch ME
                     disp('Error: ', ME.message);
                 end
             else
                 if ~isempty(app.roi) && isvalid(app.roi)
                    delete(app.roi);
                    delete(app.L);
                    delete(app.L2);
                 end
             end

        end

        % Button pushed function: ResetROIButton
        function ResetROIButtonPushed(app, event)
            %Check if roi handle exists
            if ~isempty(app.roi) && isvalid(app.roi)  
                app.roi.Position = app.inRectPos;
                app.ROIXPositionSpinner.Value = app.inRectPos(1);
                app.ROIYPositionSpinner.Value = app.inRectPos(2);
                app.ROIWidthSpinner.Value = app.inRectPos(3);
                app.ROIHeightSpinner.Value = app.inRectPos(4);
            end           
        end

        % Button pushed function: ApplyROIButton
        function ApplyROIButtonPushed(app, event)
            app.newRectPos = round(app.roi.Position);
            stoppreview(app.pupilcamera);
            stop(app.pupilcamera);
            delete(app.hImage);
            app.SelectROIButton.Value = 0;
            app.pupilcamera.ROIPosition = app.newRectPos;
            app.PupilCameraUIAxes.XLim = [0 app.newRectPos(3)];
            app.PupilCameraUIAxes.YLim = [0 app.newRectPos(4)];
            app.ROIXPositionSpinner.Value = app.newRectPos(1);
            app.ROIYPositionSpinner.Value = app.newRectPos(2);
            app.ROIWidthSpinner.Value = app.newRectPos(3);
            app.ROIHeightSpinner.Value = app.newRectPos(4);
            emptyImage = zeros(app.newRectPos(4), app.newRectPos(3), app.nbands, 'uint8'); %create empty image for axis
            app.hImage = image(app.PupilCameraUIAxes, emptyImage);
            preview(app.pupilcamera, app.hImage);

        end

        % Button pushed function: UndoApplyButton
        function UndoApplyButtonPushed(app, event)
            stoppreview(app.pupilcamera);
            app.SelectROIButton.Value = 0;
            stop(app.pupilcamera);
            delete(app.hImage);
            app.pupilcamera.ROIPosition = app.inRectPos;
            app.PupilCameraUIAxes.XLim = [0 app.inRectPos(3)];
            app.PupilCameraUIAxes.YLim = [0 app.inRectPos(4)];
            app.ROIXPositionSpinner.Value = app.inRectPos(1);
            app.ROIYPositionSpinner.Value = app.inRectPos(2);
            app.ROIWidthSpinner.Value = app.inRectPos(3);
            app.ROIHeightSpinner.Value = app.inRectPos(4);
            emptyImage = zeros(app.inRectPos(3), app.inRectPos(4), app.nbands, 'uint8'); %create empty image for axis
            app.hImage = image(app.PupilCameraUIAxes, emptyImage);
            preview(app.pupilcamera, app.hImage);
        end

        % Value changed function: ROIXPositionSpinner
        function ROIXPositionSpinnerValueChanged(app, event)
            value = app.ROIXPositionSpinner.Value;
            if value
                app.newRectPos = [app.ROIXPositionSpinner.Value, app.ROIYPositionSpinner.Value, app.ROIWidthSpinner.Value, app.ROIHeightSpinner.Value];
                app.roi.Position = app.newRectPos;
            end 
            
        end

        % Value changed function: ROIYPositionSpinner
        function ROIYPositionSpinnerValueChanged(app, event)
            value = app.ROIYPositionSpinner.Value;
            if value
                app.newRectPos = [app.ROIXPositionSpinner.Value, app.ROIYPositionSpinner.Value, app.ROIWidthSpinner.Value, app.ROIHeightSpinner.Value];
                app.roi.Position = app.newRectPos;
            end 
            
        end

        % Value changed function: ROIHeightSpinner
        function ROIHeightSpinnerValueChanged(app, event)
            value = app.ROIHeightSpinner.Value;
            if value
                app.newRectPos = [app.ROIXPositionSpinner.Value, app.ROIYPositionSpinner.Value, app.ROIWidthSpinner.Value, app.ROIHeightSpinner.Value];
                app.roi.Position = app.newRectPos;
            end 
            
        end

        % Value changed function: ROIWidthSpinner
        function ROIWidthSpinnerValueChanged(app, event)
            value = app.ROIWidthSpinner.Value;
            if value
                app.newRectPos = [app.ROIXPositionSpinner.Value, app.ROIYPositionSpinner.Value, app.ROIWidthSpinner.Value, app.ROIHeightSpinner.Value];
                app.roi.Position = app.newRectPos;
            end       
        end

        % Value changed function: CamTriggerBox
        function CamTriggerBoxValueChanged(app, event)
            app.PupilCameraCheckBox.Value = app.CamTriggerBox.Value;
        end

        % Button pushed function: ConfigureCameraButton
        function ConfigureCameraButtonPushed(app, event)
            %Turn off preview of camera to close out things 
            app.PreviewCameraSwitch.Value = "Off";
            app.PreviewCameraSwitchValueChanged;

            FrameRate = app.CamFrameRateEditField.Value;
            %disp(FrameRate);

            if isempty(app.newRectPos)
                app.pupilcamera.ROIPosition = app.inRectPos;
            else
                app.pupilcamera.ROIPosition = app.newRectPos;
            end
 
           if app.ExperimentTimeEditField.Value == 0
               exptime = 10; %Default experiment time to 10minutes
               FPT = FrameRate*60*exptime;
               app.pupilcamera.FramesPerTrigger = FPT;
               app.pupilcamera.TriggerRepeat = 0;
           else
               exptime = app.ExperimentTimeEditField.Value;
               FPT = FrameRate*60*exptime;
               app.pupilcamera.FramesPerTrigger = FPT;
               app.pupilcamera.TriggerRepeat = 0;
           end

            %Set logging properties of camera, will not set trigger yet,
            %but will set that once experiment is finally set to start
            fullnameVid = fullfile(app.saveFilePath,app.saveFileName);
            disp(fullnameVid); %Display
            logfile= VideoWriter(fullnameVid, app.CamFileFormat.Value);
            %disp(logfile);

            logfile.FrameRate= FrameRate;
            app.pupilcamera.LoggingMode = "disk";
            app.pupilcamera.DiskLogger = logfile;

             %Camera configurations
             app.srccam.AcquisitionFrameRate= FrameRate;
             app.srccam.ExposureTime= app.CamExposureSpinner.Value;
             app.srccam.Gain= app.CamGainSpinner.Value;

             %Select camera checkbox
             app.PupilCameraCheckBox.Value = 1;

             if app.UVLEDSpinner.Value > 0
                 app.UVLedCheckBox.Value = 1;
             end

             if app.IRLEDSpinner.Value > 0
                 app.IRLedCheckBox.Value = 1;
             end

             if app.CamTriggerBox.Value == 1
                triggerconfig(app.pupilcamera, "hardware", "DeviceSpecific", "DeviceSpecific");
                app.srccam.TriggerSelector = "AcquisitionStart";
                app.srccam.TriggerSource = "Line0";
                app.srccam.TriggerActivation = "RisingEdge";
                app.srccam.TriggerMode = "On";
             else
                 app.srccam.TriggerMode = "Off";
             end


        end

        % Value changed function: WheelSamplingPeriod
        function WheelSamplingPeriodValueChanged(app, event)
            val = round(app.WheelSamplingPeriod.Value);
            app.StatusVector(5) = val;
        end

        % Value changed function: StartWheelPreviewButton
        function StartWheelPreviewButtonValueChanged(app, event)
            val = 1;
            app.StatusVector(6) = val; %Reset RTC
            disp(app.StatusVector);
            sendstatus(app);
            
            app.h = animatedline(app.LocomotionUIAxes, "LineWidth",1,"Color",[0.27, 0.35, 0.16]);
            startTime = tic;
            while app.StartWheelPreviewButton.Value == 1
                if app.SerialObject.NumBytesAvailable>0
                   % Read one line of data
                   theData = fgetl(app.SerialObject);                      
                   % Convert string data to numbers
                   numericData = str2double(split(strip(theData), ','));
                   encoder_value = numericData(3); %3rd value is encoder. First two are time from millis() and RTC
                   elapsedTime = toc(startTime);
                   time_interval = elapsedTime - app.previous_time;
                   velocity = (encoder_value * app.cm_per_step) / (time_interval); % Convert time_interval to seconds

                   %store new data
                   app.timeData = [app.timeData, elapsedTime];
                   app.velocityData = [app.velocityData, velocity];

                   %remove points that have passed a point to not
                   %overload graph with a ton of data points
                   vRangeStart = max(0, elapsedTime-20);
                   mask = app.timeData > vRangeStart;
                   app.timeData = app.timeData(mask);
                   app.velocityData = app.velocityData(mask);

                   %Clear and redraw animated line
                   clearpoints(app.h);
                   addpoints(app.h, app.timeData, app.velocityData); %add points representing velocity to plot
                    
                   %Update axes limits
                   if elapsedTime < 20 
                       app.LocomotionUIAxes.XLim = [0 20];
                   else 
                       app.LocomotionUIAxes.XLim = [elapsedTime-20, elapsedTime]; % Then start scrolling.
                   end
                   app.previous_time = elapsedTime;
                   drawnow;
                else
                    encoder_value = 0;
                    elapsedTime = toc(startTime);
                    time_interval = elapsedTime - app.previous_time;
                    velocity = (encoder_value * app.cm_per_step) / (time_interval); % Convert time_interval to seconds

                    %store new data
                    app.timeData = [app.timeData, elapsedTime];
                    app.velocityData = [app.velocityData, velocity];

                    %remove points that have passed a point to not
                    %overload graph with a ton of data points
                    vRangeStart = max(0, elapsedTime-20);
                    mask = app.timeData > vRangeStart;
                    app.timeData = app.timeData(mask);
                    app.velocityData = app.velocityData(mask);

                    %Clear and redraw animated line
                    clearpoints(app.h);
                    addpoints(app.h, app.timeData, app.velocityData); %add points representing velocity to plot

                    if elapsedTime < 20
                        app.LocomotionUIAxes.XLim = [0 20];
                    else
                        app.LocomotionUIAxes.XLim = [elapsedTime-20, elapsedTime];
                    end
                    %Update previous values for next iteration
                    app.previous_time = elapsedTime;
                    drawnow;
                end
            end
            
        end

        % Button pushed function: StopWheelPreview
        function StopWheelPreviewButtonPushed(app, event)
            app.StartWheelPreviewButton.Value = 0; %stop reading wheel data
            clearpoints(app.h);
            app.LocomotionUIAxes.XLim = [0 20]; % reset axis limit
            app.previous_time = 0;
            app.timeData = [];
            app.velocityData = [];
        end

        % Button pushed function: ConfigureWheelButton
        function ConfigureWheelButtonPushed(app, event)
            val = round(app.WheelSamplingPeriod.Value);
            app.StatusVector(5) = val; %save wheel sampling speed into vector
            app.LocomotionWheelCheckBox.Value = 1;

            if app.StartWheelPreviewButton.Value == 1
                app.StopWheelPreviewButtonPushed; %stop wheel preview
            end

            %Create file to store wheel data
            app.WheelLog = []; %empty matrix to store data
            
            filetypeWheel = app.WheelFiletypeDropDown.Value;

            if contains(filetypeWheel, "csv")
                filewheel = append(app.saveFileName,"_","Locomotion.csv");
            else
                filewheel = append(app.saveFileName,"_","Locomotion.xlsx");
            end
            
            app.fullfilenamewheel = fullfile(app.saveFilePath, filewheel);
            app.ConfigureWheelStatusEditField.Value = 'Wheel is configured';

        end

        % Value changed function: PreviewLocomotionWheelCheckBox
        function PreviewLocomotionWheelCheckBoxValueChanged(app, event)

            if app.PreviewLocomotionWheelCheckBox.Value == 1
               %Set up properties to preview wheel data
               app.FinalLocomotionWheelUIAxes.YLim = [-20 20];
               app.FinalLocomotionWheelUIAxes.YTick = -20:5:20;
               app.FinalLocomotionWheelUIAxes.XLim = [0 20];
               app.FinalLocomotionWheelUIAxes.YGrid = "on";
               app.FinalLocomotionWheelUIAxes.XGrid = "on";
               app.FinalLocomotionWheelUIAxes.Visible = "on"; 

            else
               %Set up properties to preview wheel data
               app.FinalLocomotionWheelUIAxes.YLim = [-20 20];
               app.FinalLocomotionWheelUIAxes.YTick = -20:5:20;
               app.FinalLocomotionWheelUIAxes.XLim = [0 20];
               app.FinalLocomotionWheelUIAxes.YGrid = "off";
               app.FinalLocomotionWheelUIAxes.XGrid = "off";
               app.FinalLocomotionWheelUIAxes.Visible = "off"; 
            end
            
        end

        % Value changed function: PreviewPupilCameraCheckBox
        function PreviewPupilCameraCheckBoxValueChanged(app, event)
            if app.PreviewPupilCameraCheckBox.Value==1
                if isvalid(app.pupilcamera)
                    roifinal = app.pupilcamera.ROIPosition;
                    app.FinalPupilCameraUIAxes.XLim = [0 roifinal(3)];
                    app.FinalPupilCameraUIAxes.YLim = [0 roifinal(4)];
                    emptyImage = zeros(roifinal(4), roifinal(3), app.nbands, 'uint8'); %create empty image for axis
                    app.fImage = image(app.FinalPupilCameraUIAxes, emptyImage);
                else
                    app.pupilcamera = eval(app.camString); % reconnect camera
                    app.ConfigureCameraButtonPushed; %Redo all format of camera
                    roifinal = app.pupilcamera.ROIPosition;
                    app.FinalPupilCameraUIAxes.XLim = [0 roifinal(3)];
                    app.FinalPupilCameraUIAxes.YLim = [0 roifinal(4)];
                    emptyImage = zeros(roifinal(4), roifinal(3), app.nbands, 'uint8'); %create empty image for axis
                    app.fImage = image(app.FinalPupilCameraUIAxes, emptyImage);
                end 
              
            else
               %if preview camera not selected
               closepreview(app.pupilcamera);
               app.FinalPupilCameraUIAxes.Visible = "off";

               if isvalid(app.fImage)
                   delete(app.fImage);
               end
            end
                     
        end

        % Value changed function: CalciumImagingCheckBox
        function CalciumImagingCheckBoxValueChanged(app, event)
            if app.CalciumImagingCheckBox.Value == 1
               app.StatusVector(7) = 1; %Update status vector
            else
                app.StatusVector(7) = 0;
            end
            
        end

        % Value changed function: PupilCameraCheckBox
        function PupilCameraCheckBoxValueChanged(app, event)
            if app.PupilCameraCheckBox.Value == 1
                newval = 1;
                app.StatusVector(3) = newval ; %Update status vector
            else
                newval = 0;
                app.StatusVector(3) = newval;
            end
            
        end

        % Value changed function: UVLedCheckBox
        function UVLedCheckBoxValueChanged(app, event)
            if app.UVLedCheckBox.Value == 1
                newval = app.UVLEDSpinner.Value;
                app.StatusVector(1) = newval;
            else
                newval = 0;
                app.StatusVector(1) = newval;
            end
            
        end

        % Value changed function: LocomotionWheelCheckBox
        function LocomotionWheelCheckBoxValueChanged(app, event)
            if app.LocomotionWheelCheckBox.Value == 1
                newval = app.WheelSamplingPeriod.Value;
                app.StatusVector(5) = newval;
            else
                newval = 50;
                app.StatusVector(5) = newval;
            end
            
        end

        % Value changed function: IRLedCheckBox
        function IRLedCheckBoxValueChanged(app, event)
            if app.IRLedCheckBox.Value == 1
                newval = app.IRLEDSpinner.Value;
                app.StatusVector(2) = newval;
            else
                newval = 0;
                app.StatusVector(2) = newval;
            end
            
        end

        % Button pushed function: LoadExperimentParametersButton
        function LoadExperimentParametersButtonPushed(app, event)
            % Go through all callback functions to assign final serial
            % string to send during experiment
            app.CalciumImagingCheckBoxValueChanged;
            app.PupilCameraCheckBoxValueChanged;
            app.UVLedCheckBoxValueChanged;
            app.IRLedCheckBoxValueChanged;
            app.LocomotionWheelCheckBoxValueChanged;

            %Assign RTC to be reset to 0
            rtc = 1;
            app.StatusVector(6) = rtc;

            %Set up preview window
            if app.PreviewPupilCameraCheckBox.Value == 1
                app.FinalPupilCameraUIAxes.Visible = "on";
                preview(app.pupilcamera, app.fImage);
                start(app.pupilcamera);
            end

            app.TrackingStatusEditField.Value = 'Devices Loaded';
            disp(app.StatusVector);

        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            app.h2 = animatedline(app.FinalLocomotionWheelUIAxes, "LineWidth",1,"Color",[0.27, 0.35, 0.16]);
            sendstatus(app);
            startTime = tic;
            while (app.pupilcamera.FramesAcquired < app.pupilcamera.FramesPerTrigger) && (app.StartButton.Value == 1)
                if app.SerialObject.NumBytesAvailable>0
                   % Read one line of data
                   theData = fgetl(app.SerialObject);                      
                   % Convert string data to numbers
                   numericData = str2double(split(strip(theData), ','));

                   encoder_value = numericData(3); %3rd value is encoder. First two are time from millis() and RTC
                   elapsedTime = toc(startTime);
                   time_interval = elapsedTime - app.previous_time;
                   velocity = (encoder_value * app.cm_per_step) / (time_interval); % Convert time_interval to seconds

                   %store new data
                   app.timeData = [app.timeData, elapsedTime];
                   app.velocityData = [app.velocityData, velocity];

                   %log new data
                   numericData = [numericData; velocity; elapsedTime];
                   app.WheelLog = [app.WheelLog numericData];

                   %remove points that have passed a point to not
                   %overload graph with a ton of data points
                   vRangeStart = max(0, elapsedTime-20);
                   mask = app.timeData > vRangeStart;
                   app.timeData = app.timeData(mask);
                   app.velocityData = app.velocityData(mask);

                   %Clear and redraw animated line
                   clearpoints(app.h2);
                   addpoints(app.h2, app.timeData, app.velocityData); %add points representing velocity to plot
                       
                   %Update axes limits
                   if elapsedTime < 20
                       app.FinalLocomotionWheelUIAxes.XLim = [0 20];
                   else
                       app.FinalLocomotionWheelUIAxes.XLim = [elapsedTime-20, elapsedTime]; % Then start scrolling.
                   end
                   app.previous_time = elapsedTime;
                   drawnow;
                    
                else
                    encoder_value = 0;
                    elapsedTime = toc(startTime);
                    time_interval = elapsedTime - app.previous_time;
                    velocity = (encoder_value * app.cm_per_step) / (time_interval); % Convert time_interval to seconds

                    %store new data
                    app.timeData = [app.timeData, elapsedTime];
                    app.velocityData = [app.velocityData, velocity];

                    %remove points that have passed a point to not
                    %overload graph with a ton of data points
                    vRangeStart = max(0, elapsedTime-20);
                    mask = app.timeData > vRangeStart;
                    app.timeData = app.timeData(mask);
                    app.velocityData = app.velocityData(mask);

                    %Clear and redraw animated line
                    clearpoints(app.h2);
                    addpoints(app.h2, app.timeData, app.velocityData); %add points representing velocity to plot

                    if elapsedTime < 20
                        app.FinalLocomotionWheelUIAxes.XLim = [0 20];
                    else
                        app.FinalLocomotionWheelUIAxes.XLim = [elapsedTime-20, elapsedTime];
                    end

                    %Update previous values for next iteration
                    app.previous_time = elapsedTime;
                    drawnow;
                end
            end

            closepreview(app.pupilcamera);
            if ~isempty(app.WheelLog)
                writematrix(app.WheelLog, app.fullfilenamewheel);
            end

            app.StatusVector = [0,0,0,0,50,0,0,0,0,0]; %Turn everything off
            sendstatus(app);            
            app.StartButton.Value = 0;
            app.TrackingStatusEditField.Value = 'Experiment Completed';
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StartButton.Value = 0;
            closepreview(app.pupilcamera);
            stop(app.pupilcamera);
            app.StatusVector = [0,0,0,0,50,0,0,0,0,0]; %Turn everything off
            sendstatus(app);
            clearpoints(app.h2);
            app.PreviewLocomotionWheelCheckBox = 0;
            app.PreviewLocomotionWheelCheckBoxValueChanged;
            app.PreviewPupilCameraCheckBox = 0;
            app.PreviewPupilCameraCheckBoxValueChanged;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.StatusVector = [0,0,0,0,50,0,0,0,0,0]; %Turn everything off
                if exist('app.SerialObject', 'var')  
                    if strcmp(app.SerialObject.Status, 'open')
                        sendstatus(app);
                        delete(app.SerialObject);
                    end
                end

                if exist('app.pupilcamera','var')
                    if isvalid(app.pupilcamera)
                        app.srccam.TriggerMode = "Off";
                        delete(app.pupilcamera);
                    end
                end
                delete(app);
                delete(imaqfind);
                imaqreset;
                clear;
                clc;
            catch ME
                disp(ME.message)
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {702, 702};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {38, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.TabGroup2.Tag = 'TabGroupOne';
            app.SetupTab = uitab(app.TabGroup2);
            app.SetupTab.Title = 'Setup';
            app.SetupTab.Tag = 'SetupTab';
            app.Image = uiimage(app.SetupTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'CaPuLeT Logo.jpeg');
            app.CaPuLeTLabel = uilabel(app.SetupTab);
            app.CaPuLeTLabel.Text = {'CaPuLeT'; ''};
            app.CalciumimagingPupillometryandLocomotionestimatedTrackingLabel = uilabel(app.SetupTab);
            app.CalciumimagingPupillometryandLocomotionestimatedTrackingLabel.Text = {'Calcium-imaging, Pupillometry,'; 'and Locomotion-estimated Tracking'};
            app.Label = uilabel(app.SetupTab);
            app.Label.Text = '___';
            app.Label_2 = uilabel(app.SetupTab);
            app.Label_2.Text = '___';
            app.Label_3 = uilabel(app.SetupTab);
            app.Label_3.Text = '__';
            app.Label_4 = uilabel(app.SetupTab);
            app.Label_4.Text = '__';
            app.Label_5 = uilabel(app.SetupTab);
            app.Label_5.Text = '__';
            app.TabGroup = uitabgroup(app.SetupTab);
            app.TabGroup.Tag = 'SetUpTabGroup';
            app.DeviceTab = uitab(app.TabGroup);
            app.DeviceTab.Title = 'Device ';
            app.COMportDropDownLabel = uilabel(app.DeviceTab);
            app.COMportDropDownLabel.Text = 'COM port:';
            app.COMportDropDown = uidropdown(app.DeviceTab);
            app.COMportDropDown.ValueChangedFcn = createCallbackFcn(app, @COMportDropDownValueChanged, true);
            app.COMportDropDown.Tag = 'SerialCOMportDropDown';
            app.RefreshPortButton = uibutton(app.DeviceTab, 'push');
            app.RefreshPortButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshPortButtonPushed, true);
            app.RefreshPortButton.Tag = 'RefreshSerialPortButton';
            app.RefreshPortButton.Text = 'Refresh';
            app.StatusEditField_2Label = uilabel(app.DeviceTab);
            app.StatusEditField_2Label.Text = 'Status:';
            app.SerialStatusEditField = uieditfield(app.DeviceTab, 'text');
            app.SerialStatusEditField.Tag = 'SerialStatusEditField';
            app.Lamp = uilamp(app.DeviceTab);
            app.Lamp.Tag = 'SerialLamp';
            app.SConnectButton = uibutton(app.DeviceTab, 'state');
            app.SConnectButton.ValueChangedFcn = createCallbackFcn(app, @SConnectButtonValueChanged, true);
            app.SConnectButton.Tag = 'SerialConnectButton';
            app.SConnectButton.Text = 'Connect ';
            app.SDisconnectButton = uibutton(app.DeviceTab, 'push');
            app.SDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @SDisconnectButtonPushed, true);
            app.SDisconnectButton.Tag = 'SDisconnectButton';
            app.SDisconnectButton.Text = 'Disconnect';
            app.ExperimentInfoTab = uitab(app.TabGroup);
            app.ExperimentInfoTab.Title = 'Experiment Info';
            app.ExperimentInfoTab.Tag = 'ExperimentInfoTab';
            app.SessiontypeLabel = uilabel(app.ExperimentInfoTab);
            app.SessiontypeLabel.Text = 'Session type:';
            app.SessiontypeDropDown = uidropdown(app.ExperimentInfoTab);
            app.SessiontypeDropDown.Items = {'Habituation', 'Experiment', 'Option 3', 'Option 4'};
            app.SessiontypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SessiontypeDropDownValueChanged, true);
            app.SessiontypeDropDown.Value = 'Habituation';
            app.ExperimentTimeEditFieldLabel = uilabel(app.ExperimentInfoTab);
            app.ExperimentTimeEditFieldLabel.Text = 'Experiment time (minutes):';
            app.ExperimentTimeEditField = uieditfield(app.ExperimentInfoTab, 'numeric');
            app.ExperimentTimeEditField.RoundFractionalValues = 'on';
            app.ExperimentTimeEditField.Tag = 'ExperimentTimeEditField';
            app.ResearcherNameEditFieldLabel = uilabel(app.ExperimentInfoTab);
            app.ResearcherNameEditFieldLabel.Text = {'Researcher '; 'Initials:'};
            app.ResearcherNameEditField = uieditfield(app.ExperimentInfoTab, 'text');
            app.ResearcherNameEditField.CharacterLimits = [0 10];
            app.ResearcherNameEditField.ValueChangedFcn = createCallbackFcn(app, @ResearcherNameEditFieldValueChanged, true);
            app.ResearcherNameEditField.Tag = 'ResearchInitialsEditField';
            app.ProjectNameEditFieldLabel = uilabel(app.ExperimentInfoTab);
            app.ProjectNameEditFieldLabel.Text = 'Project name:';
            app.ProjectNameEditField = uieditfield(app.ExperimentInfoTab, 'text');
            app.ProjectNameEditField.CharacterLimits = [0 50];
            app.ProjectNameEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectNameEditFieldValueChanged, true);
            app.ProjectNameEditField.Tag = 'ProjectNameEditField';
            app.MouseIDEditFieldLabel = uilabel(app.ExperimentInfoTab);
            app.MouseIDEditFieldLabel.Text = 'Mouse ID:';
            app.MouseIDEditField = uieditfield(app.ExperimentInfoTab, 'text');
            app.MouseIDEditField.CharacterLimits = [0 50];
            app.MouseIDEditField.ValueChangedFcn = createCallbackFcn(app, @MouseIDEditFieldValueChanged, true);
            app.MouseIDEditField.Tag = 'MouseIDEditField';
            app.MouseGenotypeEditFieldLabel = uilabel(app.ExperimentInfoTab);
            app.MouseGenotypeEditFieldLabel.Text = 'Mouse Genotype:';
            app.MouseGenotypeEditField = uieditfield(app.ExperimentInfoTab, 'text');
            app.MouseGenotypeEditField.CharacterLimits = [0 50];
            app.MouseGenotypeEditField.ValueChangedFcn = createCallbackFcn(app, @MouseGenotypeEditFieldValueChanged, true);
            app.MouseGenotypeEditField.Tag = 'GenotypeEditField';
            app.StimulusEditFieldLabel = uilabel(app.ExperimentInfoTab);
            app.StimulusEditFieldLabel.Text = 'Stimulus:';
            app.StimulusEditField = uieditfield(app.ExperimentInfoTab, 'text');
            app.StimulusEditField.CharacterLimits = [0 50];
            app.StimulusEditField.ValueChangedFcn = createCallbackFcn(app, @StimulusEditFieldValueChanged, true);
            app.StimulusEditField.Tag = 'StimulusEditField';
            app.FileSaveTab = uitab(app.TabGroup);
            app.FileSaveTab.Title = 'File Save';
            app.DateDatePickerLabel = uilabel(app.FileSaveTab);
            app.DateDatePickerLabel.Text = 'Date:';
            app.DateDatePicker = uidatepicker(app.FileSaveTab);
            app.DateDatePicker.DisplayFormat = 'dd.MM.uuuu';
            app.DateDatePicker.ValueChangedFcn = createCallbackFcn(app, @DateDatePickerValueChanged, true);
            app.DateDatePicker.Tag = 'DateDatePicker';
            app.FolderpathEditFieldLabel = uilabel(app.FileSaveTab);
            app.FolderpathEditFieldLabel.Text = 'Folder path :';
            app.FolderpathEditField = uieditfield(app.FileSaveTab, 'text');
            app.BrowseButton = uibutton(app.FileSaveTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SetfilenameButton = uibutton(app.FileSaveTab, 'push');
            app.SetfilenameButton.ButtonPushedFcn = createCallbackFcn(app, @SetfilenameButtonPushed, true);
            app.SetfilenameButton.Text = 'Set filename';
            app.FilenamestatusLabel = uilabel(app.FileSaveTab);
            app.FilenamestatusLabel.Text = 'Filename status';
            app.FilenamestatusEditField = uieditfield(app.FileSaveTab, 'text');
            app.FilenamestatusEditField.Tag = 'FilenamestatusEditField';
            app.CalciumimagingTab = uitab(app.TabGroup2);
            app.CalciumimagingTab.Title = 'Calcium-imaging';
            app.CalciumimagingboxUIAxes = uiaxes(app.CalciumimagingTab);
            app.CalciumimagingboxUIAxes.XTick = [];
            app.CalciumimagingboxUIAxes.XTickLabel = '';
            app.CalciumimagingboxUIAxes.YTick = [];
            app.CalciumimagingboxUIAxes.ZTick = [];
            app.CalciumimagingboxUIAxes.Tag = 'CalciumimagingboxUIAxes';
            app.InorderintegrateneuralrecordingsormodulationLabel = uilabel(app.CalciumimagingTab);
            app.InorderintegrateneuralrecordingsormodulationLabel.Text = {'To synchronize calcium-imaging recordings with pupillometry and locomotion tracking, please configure multi-photon aquisition system to receive a TTL (Transistor-Transistor Logic) pulse. This setting is necessary to trigger the synchronized data collection process.'; ''; 'Note that the trigger delay time on the multi-photon system may need to be adjusted to account for the time the trigger signal from CaPuLeT will be received. '};
            app.CalciumimagingdevicesPanel = uipanel(app.CalciumimagingTab);
            app.CalciumimagingdevicesPanel.Title = 'Calcium-imaging devices';
            app.TwoPhotonCheckBox = uicheckbox(app.CalciumimagingdevicesPanel);
            app.TwoPhotonCheckBox.ValueChangedFcn = createCallbackFcn(app, @TwoPhotonCheckBoxValueChanged, true);
            app.TwoPhotonCheckBox.Tag = 'TwoPhotonCheckBox';
            app.TwoPhotonCheckBox.Text = '';
            app.MultiphotonsystemLabel = uilabel(app.CalciumimagingdevicesPanel);
            app.MultiphotonsystemLabel.Text = {'Multi-photon '; 'system'};
            app.TwoPhotonTriggerButton = uibutton(app.CalciumimagingdevicesPanel, 'state');
            app.TwoPhotonTriggerButton.ValueChangedFcn = createCallbackFcn(app, @TwoPhotonTriggerButtonValueChanged, true);
            app.TwoPhotonTriggerButton.Text = 'Test Trigger';
            app.StatusTextAreaLabel = uilabel(app.CalciumimagingdevicesPanel);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.CalciumImagingStatusTextArea = uitextarea(app.CalciumimagingdevicesPanel);
            app.CalciumImagingStatusTextArea.Tag = 'CalciumImagingStatusTextArea';
            app.LampCalciumImagingTest = uilamp(app.CalciumimagingdevicesPanel);
            app.LampCalciumImagingTest.Tag = 'LampCalciumImagingTest';
            app.OtherdevicesPanel = uipanel(app.CalciumimagingTab);
            app.OtherdevicesPanel.Title = 'Other devices';
            app.TointegrateotherdevicestobesynchronizedwithLabel = uilabel(app.OtherdevicesPanel);
            app.TointegrateotherdevicestobesynchronizedwithLabel.Text = 'Modifications to both Arduino and MATLAB scripts are required to integrate other devices or systems. See device integration README file for instructions. ';
            app.OptogeneticsCheckBox_3 = uicheckbox(app.OtherdevicesPanel);
            app.OptogeneticsCheckBox_3.Text = 'Optogenetics';
            app.Otherdevicetesttrigger = uibutton(app.OtherdevicesPanel, 'state');
            app.Otherdevicetesttrigger.Text = 'Test Trigger';
            app.OtherCheckBox = uicheckbox(app.OtherdevicesPanel);
            app.OtherCheckBox.Text = 'Other';
            app.PupillometryTab = uitab(app.TabGroup2);
            app.PupillometryTab.Title = 'Pupillometry';
            app.TabGroup3 = uitabgroup(app.PupillometryTab);
            app.PupilCameraTab = uitab(app.TabGroup3);
            app.PupilCameraTab.Title = 'Pupil Camera';
            app.PupilCameraTab.Tag = 'PupilCameraTab';
            app.PupilCameraUIAxes = uiaxes(app.PupilCameraTab);
            app.PupilCameraUIAxes.XTick = [];
            app.PupilCameraUIAxes.YTick = [];
            app.PupilCameraUIAxes.Tag = 'PupilCameraUIAxes';
            app.CamDeviceConnectionPanel = uipanel(app.PupilCameraTab);
            app.CamDeviceConnectionPanel.Title = 'Device Connection';
            app.CamDeviceConnectionPanel.Tag = 'CamDeviceConnectionPanel';
            app.CameraDropDownLabel = uilabel(app.CamDeviceConnectionPanel);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CamDropDown = uidropdown(app.CamDeviceConnectionPanel);
            app.CamDropDown.ValueChangedFcn = createCallbackFcn(app, @CamDropDownValueChanged, true);
            app.CamDropDown.Tag = 'CamDropDown';
            app.CamDropDown.ClickedFcn = createCallbackFcn(app, @CamDropDownClicked, true);
            app.CamRefreshButton = uibutton(app.CamDeviceConnectionPanel, 'push');
            app.CamRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @CamRefreshButtonPushed, true);
            app.CamRefreshButton.Tag = 'CamRefreshButton';
            app.CamRefreshButton.Text = 'Refresh';
            app.FormatDropDownLabel = uilabel(app.CamDeviceConnectionPanel);
            app.FormatDropDownLabel.Text = 'Format';
            app.CamFormatDropDown = uidropdown(app.CamDeviceConnectionPanel);
            app.CamFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @CamFormatDropDownValueChanged, true);
            app.CamFormatDropDown.Tag = 'CamFormatDropDown';
            app.StatusLabel = uilabel(app.CamDeviceConnectionPanel);
            app.StatusLabel.Text = 'Status:';
            app.CamStatusEditField = uieditfield(app.CamDeviceConnectionPanel, 'text');
            app.CamStatusEditField.Tag = 'CamConnectStatusEditField';
            app.CamConnectButton = uibutton(app.CamDeviceConnectionPanel, 'push');
            app.CamConnectButton.ButtonPushedFcn = createCallbackFcn(app, @CamConnectButtonPushed, true);
            app.CamConnectButton.Tag = 'CamConnectButton';
            app.CamConnectButton.Text = 'Connect ';
            app.CamDisconnectButton = uibutton(app.CamDeviceConnectionPanel, 'push');
            app.CamDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @CamDisconnectButtonPushed, true);
            app.CamDisconnectButton.Tag = 'CamDisconnectButton';
            app.CamDisconnectButton.Text = 'Disconnect';
            app.PreviewCameraSwitchLabel = uilabel(app.CamDeviceConnectionPanel);
            app.PreviewCameraSwitchLabel.Text = {'Preview'; 'Camera'};
            app.PreviewCameraSwitch = uiswitch(app.CamDeviceConnectionPanel, 'slider');
            app.PreviewCameraSwitch.ValueChangedFcn = createCallbackFcn(app, @PreviewCameraSwitchValueChanged, true);
            app.PreviewCameraSwitch.Tag = 'PreviewCameraSwitch';
            app.CameraSettingsPanel = uipanel(app.PupilCameraTab);
            app.CameraSettingsPanel.Title = 'Camera Settings';
            app.FrameRateEditFieldLabel = uilabel(app.CameraSettingsPanel);
            app.FrameRateEditFieldLabel.Text = {'Acquisition Frame'; 'Rate'};
            app.CamFrameRateEditField = uieditfield(app.CameraSettingsPanel, 'numeric');
            app.CamFrameRateEditField.Limits = [0 60];
            app.CamFrameRateEditField.RoundFractionalValues = 'on';
            app.CamFrameRateEditField.ValueChangedFcn = createCallbackFcn(app, @CamFrameRateEditFieldValueChanged, true);
            app.CamFrameRateEditField.Tag = 'CamFrameRateEditField';
            app.ExposureSliderLabel = uilabel(app.CameraSettingsPanel);
            app.ExposureSliderLabel.Text = {'Exposure '; 'Time'};
            app.CamExposureSlider = uislider(app.CameraSettingsPanel);
            app.CamExposureSlider.MajorTicks = [];
            app.CamExposureSlider.ValueChangedFcn = createCallbackFcn(app, @CamExposureSliderValueChanged, true);
            app.CamExposureSlider.MinorTicks = [];
            app.CamExposureSlider.Tag = 'CamExposureSlider';
            app.CamExposureSpinner = uispinner(app.CameraSettingsPanel);
            app.CamExposureSpinner.RoundFractionalValues = 'on';
            app.CamExposureSpinner.ValueChangedFcn = createCallbackFcn(app, @CamExposureSpinnerValueChanged, true);
            app.CamExposureSpinner.Tag = 'CamExposureSpinner';
            app.Gain = uilabel(app.CameraSettingsPanel);
            app.Gain.Text = 'Gain';
            app.CamGainSlider = uislider(app.CameraSettingsPanel);
            app.CamGainSlider.MajorTicks = [];
            app.CamGainSlider.ValueChangedFcn = createCallbackFcn(app, @CamGainSliderValueChanged, true);
            app.CamGainSlider.MinorTicks = [];
            app.CamGainSlider.Tag = 'CamGainSlider';
            app.CamGainSpinner = uispinner(app.CameraSettingsPanel);
            app.CamGainSpinner.RoundFractionalValues = 'on';
            app.CamGainSpinner.ValueChangedFcn = createCallbackFcn(app, @CamGainSpinnerValueChanged, true);
            app.CamGainSpinner.Tag = 'CamGainSpinner';
            app.UVLabel = uilabel(app.CameraSettingsPanel);
            app.UVLabel.Text = 'UV LED';
            app.UVLEDSlider = uislider(app.CameraSettingsPanel);
            app.UVLEDSlider.MajorTicks = [];
            app.UVLEDSlider.ValueChangedFcn = createCallbackFcn(app, @UVLEDSliderValueChanged, true);
            app.UVLEDSlider.MinorTicks = [];
            app.UVLEDSlider.Tag = 'UVLEDSlider';
            app.UVLEDSpinner = uispinner(app.CameraSettingsPanel);
            app.UVLEDSpinner.RoundFractionalValues = 'on';
            app.UVLEDSpinner.ValueChangedFcn = createCallbackFcn(app, @UVLEDSpinnerValueChanged, true);
            app.UVLEDSpinner.Tag = 'UVLEDSpinner';
            app.IRLabel = uilabel(app.CameraSettingsPanel);
            app.IRLabel.Text = 'IR LED';
            app.IRLEDSlider = uislider(app.CameraSettingsPanel);
            app.IRLEDSlider.MajorTicks = [];
            app.IRLEDSlider.ValueChangedFcn = createCallbackFcn(app, @IRLEDSliderValueChanged, true);
            app.IRLEDSlider.MinorTicks = [];
            app.IRLEDSlider.Tag = 'IRLEDSlider';
            app.IRLEDSpinner = uispinner(app.CameraSettingsPanel);
            app.IRLEDSpinner.RoundFractionalValues = 'on';
            app.IRLEDSpinner.ValueChangedFcn = createCallbackFcn(app, @IRLEDSpinnerValueChanged, true);
            app.IRLEDSpinner.Tag = 'IRLEDSpinner';
            app.CamSetResetButton = uibutton(app.CameraSettingsPanel, 'push');
            app.CamSetResetButton.ButtonPushedFcn = createCallbackFcn(app, @CamSetResetButtonPushed, true);
            app.CamSetResetButton.Tag = 'CamSetResetButton';
            app.CamSetResetButton.Text = 'Reset';
            app.ROIPanel = uipanel(app.PupilCameraTab);
            app.ROIPositionButtonGroup = uibuttongroup(app.ROIPanel);
            app.ROIPositionButtonGroup.Title = 'ROI Position';
            app.SelectROIButton = uibutton(app.ROIPositionButtonGroup, 'state');
            app.SelectROIButton.ValueChangedFcn = createCallbackFcn(app, @SelectROIButtonValueChanged, true);
            app.SelectROIButton.Tag = 'SelectROIButton';
            app.SelectROIButton.Text = 'Select ROI';
            app.ResetROIButton = uibutton(app.ROIPositionButtonGroup, 'push');
            app.ResetROIButton.ButtonPushedFcn = createCallbackFcn(app, @ResetROIButtonPushed, true);
            app.ResetROIButton.Tag = 'ResetROIButton';
            app.ResetROIButton.Text = 'Reset ROI';
            app.ApplyROIButton = uibutton(app.ROIPositionButtonGroup, 'push');
            app.ApplyROIButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyROIButtonPushed, true);
            app.ApplyROIButton.Tag = 'ApplyROIButton';
            app.ApplyROIButton.Text = 'Apply';
            app.UndoApplyButton = uibutton(app.ROIPositionButtonGroup, 'push');
            app.UndoApplyButton.ButtonPushedFcn = createCallbackFcn(app, @UndoApplyButtonPushed, true);
            app.UndoApplyButton.Tag = 'UndoApplyButton';
            app.UndoApplyButton.Text = 'Undo';
            app.XPositionSpinnerLabel = uilabel(app.PupilCameraTab);
            app.XPositionSpinnerLabel.Text = 'X Position:';
            app.ROIXPositionSpinner = uispinner(app.PupilCameraTab);
            app.ROIXPositionSpinner.RoundFractionalValues = 'on';
            app.ROIXPositionSpinner.ValueChangedFcn = createCallbackFcn(app, @ROIXPositionSpinnerValueChanged, true);
            app.ROIXPositionSpinner.Tag = 'ROIXPositionSpinner';
            app.YPositionSpinnerLabel = uilabel(app.PupilCameraTab);
            app.YPositionSpinnerLabel.Text = 'Y Position:';
            app.ROIYPositionSpinner = uispinner(app.PupilCameraTab);
            app.ROIYPositionSpinner.RoundFractionalValues = 'on';
            app.ROIYPositionSpinner.ValueChangedFcn = createCallbackFcn(app, @ROIYPositionSpinnerValueChanged, true);
            app.ROIYPositionSpinner.Tag = 'ROIYPositionSpinner';
            app.HeightSpinnerLabel = uilabel(app.PupilCameraTab);
            app.HeightSpinnerLabel.Text = 'Height:';
            app.ROIHeightSpinner = uispinner(app.PupilCameraTab);
            app.ROIHeightSpinner.RoundFractionalValues = 'on';
            app.ROIHeightSpinner.ValueChangedFcn = createCallbackFcn(app, @ROIHeightSpinnerValueChanged, true);
            app.ROIHeightSpinner.Tag = 'ROIHeightSpinner';
            app.WidthSpinnerLabel = uilabel(app.PupilCameraTab);
            app.WidthSpinnerLabel.Text = 'Width:';
            app.ROIWidthSpinner = uispinner(app.PupilCameraTab);
            app.ROIWidthSpinner.RoundFractionalValues = 'on';
            app.ROIWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @ROIWidthSpinnerValueChanged, true);
            app.ROIWidthSpinner.Tag = 'ROIWidthSpinner';
            app.LoggingParametersPanel = uipanel(app.PupilCameraTab);
            app.LoggingParametersPanel.Title = 'Logging Parameters';
            app.FileFormatLabel = uilabel(app.LoggingParametersPanel);
            app.FileFormatLabel.Text = 'File Format';
            app.CamFileFormat = uidropdown(app.LoggingParametersPanel);
            app.CamFileFormat.Items = {'Motion JPEG AVI', 'Uncompressed AVI', 'MPEG-4'};
            app.CamFileFormat.Tag = 'CamFileFormat';
            app.CamFileFormat.Value = 'Motion JPEG AVI';
            app.TriggerCameraLabel = uilabel(app.LoggingParametersPanel);
            app.TriggerCameraLabel.Text = 'Trigger Camera';
            app.CamTriggerBox = uicheckbox(app.LoggingParametersPanel);
            app.CamTriggerBox.ValueChangedFcn = createCallbackFcn(app, @CamTriggerBoxValueChanged, true);
            app.CamTriggerBox.Tag = 'CamTriggerBox';
            app.CamTriggerBox.Text = '';
            app.ConfigureCameraButton = uibutton(app.LoggingParametersPanel, 'push');
            app.ConfigureCameraButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureCameraButtonPushed, true);
            app.ConfigureCameraButton.Tag = 'ConfigureCameraButton';
            app.ConfigureCameraButton.Text = 'Configure Camera';
            app.LocomotionestimationTab = uitab(app.TabGroup2);
            app.LocomotionestimationTab.Title = 'Locomotion-estimation';
            app.LocomotionwheelPanel = uipanel(app.LocomotionestimationTab);
            app.LocomotionwheelPanel.Title = 'Locomotion wheel';
            app.LocomotionUIAxes = uiaxes(app.LocomotionwheelPanel);
            xlabel(app.LocomotionUIAxes, 'Time (s)')
            ylabel(app.LocomotionUIAxes, 'Velocity (cm/s)')
            app.LocomotionUIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.LocomotionUIAxes.Tag = 'LocomotionUIAxes';
            app.AcquisitionSpeedPanel = uipanel(app.LocomotionestimationTab);
            app.AcquisitionSpeedPanel.Title = 'Acquisition Speed';
            app.SamplingratefordataHzEditFieldLabel = uilabel(app.AcquisitionSpeedPanel);
            app.SamplingratefordataHzEditFieldLabel.Text = 'Wheel sampling period (ms):';
            app.WheelSamplingPeriod = uieditfield(app.AcquisitionSpeedPanel, 'numeric');
            app.WheelSamplingPeriod.RoundFractionalValues = 'on';
            app.WheelSamplingPeriod.ValueChangedFcn = createCallbackFcn(app, @WheelSamplingPeriodValueChanged, true);
            app.StartWheelPreviewButton = uibutton(app.AcquisitionSpeedPanel, 'state');
            app.StartWheelPreviewButton.ValueChangedFcn = createCallbackFcn(app, @StartWheelPreviewButtonValueChanged, true);
            app.StartWheelPreviewButton.Tag = 'StartWheelPreviewButton';
            app.StartWheelPreviewButton.Text = 'Start Preview';
            app.StopWheelPreview = uibutton(app.AcquisitionSpeedPanel, 'push');
            app.StopWheelPreview.ButtonPushedFcn = createCallbackFcn(app, @StopWheelPreviewButtonPushed, true);
            app.StopWheelPreview.Tag = 'StopWheelPreview';
            app.StopWheelPreview.Text = 'Stop';
            app.LoggingPanel = uipanel(app.LocomotionestimationTab);
            app.LoggingPanel.Title = 'Logging ';
            app.FiletypeDropDownLabel = uilabel(app.LoggingPanel);
            app.FiletypeDropDownLabel.Text = 'File type:';
            app.WheelFiletypeDropDown = uidropdown(app.LoggingPanel);
            app.WheelFiletypeDropDown.Items = {'csv', 'xlsx', '', ''};
            app.WheelFiletypeDropDown.Value = 'csv';
            app.ConfigureWheelButton = uibutton(app.LoggingPanel, 'push');
            app.ConfigureWheelButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureWheelButtonPushed, true);
            app.ConfigureWheelButton.Tag = 'ConfigureWheelButton';
            app.ConfigureWheelButton.Text = 'Configure';
            app.StatusEditFieldLabel = uilabel(app.LoggingPanel);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.ConfigureWheelStatusEditField = uieditfield(app.LoggingPanel, 'text');
            app.ConfigureWheelStatusEditField.Tag = 'ConfigureWheelStatusEditField';
            app.TrackingTab = uitab(app.TabGroup2);
            app.TrackingTab.Title = 'Tracking';
            app.FinalLocomotionWheelUIAxes = uiaxes(app.TrackingTab);
            xlabel(app.FinalLocomotionWheelUIAxes, 'Time (s)')
            ylabel(app.FinalLocomotionWheelUIAxes, 'Velocity (cm/s)')
            app.FinalLocomotionWheelUIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.FinalLocomotionWheelUIAxes.ZTick = [];
            app.FinalLocomotionWheelUIAxes.Tag = 'FinalLocomotionWheelUIAxes';
            app.FinalLocomotionWheelUIAxes.Visible = 'off';
            app.FinalPupilCameraUIAxes = uiaxes(app.TrackingTab);
            app.FinalPupilCameraUIAxes.XTick = [];
            app.FinalPupilCameraUIAxes.YTick = [];
            app.FinalPupilCameraUIAxes.Tag = 'FinalPupilCameraUIAxes';
            app.FinalPupilCameraUIAxes.Visible = 'off';
            app.SelectedDevicesforExperimentPanel = uipanel(app.TrackingTab);
            app.SelectedDevicesforExperimentPanel.Title = 'Selected Devices for Experiment';
            app.SelectedDevicesforExperimentPanel.Tag = 'DevicesConfiguredforExperiment';
            app.CalciumImagingCheckBox = uicheckbox(app.SelectedDevicesforExperimentPanel);
            app.CalciumImagingCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalciumImagingCheckBoxValueChanged, true);
            app.CalciumImagingCheckBox.Tag = 'CalciumImagingCheckBox';
            app.CalciumImagingCheckBox.Text = 'Calcium-Imaging';
            app.UVLedCheckBox = uicheckbox(app.SelectedDevicesforExperimentPanel);
            app.UVLedCheckBox.ValueChangedFcn = createCallbackFcn(app, @UVLedCheckBoxValueChanged, true);
            app.UVLedCheckBox.Text = 'UV LED';
            app.PupilCameraCheckBox = uicheckbox(app.SelectedDevicesforExperimentPanel);
            app.PupilCameraCheckBox.ValueChangedFcn = createCallbackFcn(app, @PupilCameraCheckBoxValueChanged, true);
            app.PupilCameraCheckBox.Tag = 'PupilCameraCheckBox';
            app.PupilCameraCheckBox.Text = 'Pupil Camera';
            app.IRLedCheckBox = uicheckbox(app.SelectedDevicesforExperimentPanel);
            app.IRLedCheckBox.ValueChangedFcn = createCallbackFcn(app, @IRLedCheckBoxValueChanged, true);
            app.IRLedCheckBox.Tag = 'IRLedCheckBox';
            app.IRLedCheckBox.Text = 'IR LED';
            app.LocomotionWheelCheckBox = uicheckbox(app.SelectedDevicesforExperimentPanel);
            app.LocomotionWheelCheckBox.ValueChangedFcn = createCallbackFcn(app, @LocomotionWheelCheckBoxValueChanged, true);
            app.LocomotionWheelCheckBox.Text = 'Locomotion Wheel ';
            app.Otherdevice = uicheckbox(app.SelectedDevicesforExperimentPanel);
            app.Otherdevice.Tag = 'OptogeneticsCheckBox2';
            app.Otherdevice.Text = 'Other';
            app.PreviewWindowPanel = uipanel(app.TrackingTab);
            app.PreviewWindowPanel.Title = 'Preview Window';
            app.PreviewWindowPanel.Tag = 'DevicesConfiguredforExperiment';
            app.PreviewPupilCameraCheckBox = uicheckbox(app.PreviewWindowPanel);
            app.PreviewPupilCameraCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreviewPupilCameraCheckBoxValueChanged, true);
            app.PreviewPupilCameraCheckBox.Tag = 'PupilCameraCheckBox';
            app.PreviewPupilCameraCheckBox.Text = 'Pupil Camera';
            app.PreviewLocomotionWheelCheckBox = uicheckbox(app.PreviewWindowPanel);
            app.PreviewLocomotionWheelCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreviewLocomotionWheelCheckBoxValueChanged, true);
            app.PreviewLocomotionWheelCheckBox.Tag = 'PreviewLocomotionWheelCheckBox';
            app.PreviewLocomotionWheelCheckBox.Text = 'Locomotion Wheel ';
            app.StartButton = uibutton(app.TrackingTab, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = ' Start';
            app.StopButton = uibutton(app.TrackingTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.LoadExperimentParametersButton = uibutton(app.TrackingTab, 'push');
            app.LoadExperimentParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExperimentParametersButtonPushed, true);
            app.LoadExperimentParametersButton.Text = 'Load Experiment Parameters';
            app.StatusEditFieldLabel_2 = uilabel(app.TrackingTab);
            app.StatusEditFieldLabel_2.Text = 'Status:';
            app.TrackingStatusEditField = uieditfield(app.TrackingTab, 'text');
            app.TrackingStatusEditField.Tag = 'TrackingStatusEditField';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CaPuLeT_GUI_2P_Audio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end